classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        houghTab                 matlab.ui.container.Tab
        hough_UploadImageButton  matlab.ui.control.Button
        hough_base_img           matlab.ui.control.Image
        hough_res_axes           matlab.ui.control.UIAxes
        Tab2                     matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: hough_UploadImageButton
        function hough_UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            app.hough_base_img.ImageSource=img;
            i=im2gray(img);
            bw=imbinarize(i,0.5);
            [h,theta,rho]=houghtr(bw);
            hpeaks=houghpeaks(h,5,'threshold',ceil(0.3*max(h(:))));
            x=theta(hpeaks(:,2));
            y=rho(hpeaks(:,2));
            hlines=houghlines(bw,theta,rho,hpeaks,'FillGap',5,'MinLength',7);
            
            % figure, imshow(img), hold on
            imshow(img,Parent=app.hough_res_axes);
            hold(app.hough_res_axes,"on");
            max_len=0;
            for k=1:length(hlines)
                xy=[hlines(k).point1;hlines(k).point2];
                plot(app.hough_res_axes,xy(:,1),xy(:,2),'LineWidth',2,'Color','green');
            
                plot(app.hough_res_axes,xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow');
                plot(app.hough_res_axes,xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red');
            
                len = norm(hlines(k).point1 - hlines(k).point2);
                if ( len > max_len) 
                    max_len = len; 
                    xy_long = xy; 
                end
            end
            plot(app.hough_res_axes,xy_long(:,1),xy_long(:,2),'LineWidth',2,'Color','red');
            hold(app.hough_res_axes,"off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.houghTab = uitab(app.TabGroup);
            app.houghTab.Title = 'hough';
            app.hough_res_axes = uiaxes(app.houghTab);
            title(app.hough_res_axes, 'Result')
            app.hough_res_axes.XTick = [];
            app.hough_res_axes.YTick = [];
            app.hough_base_img = uiimage(app.houghTab);
            app.hough_UploadImageButton = uibutton(app.houghTab, 'push');
            app.hough_UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @hough_UploadImageButtonPushed, true);
            app.hough_UploadImageButton.Text = 'Upload Image';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end